classdef PPPH_UAV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main                           matlab.ui.Figure
        MainLabel                      matlab.ui.control.Label
        SaveConfigurationButton        matlab.ui.control.Button
        DefaultConfigurationButton     matlab.ui.control.Button
        LoadSavedConfigurationButton   matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        Tabs                           matlab.ui.container.TabGroup
        Input                          matlab.ui.container.Tab
        RequiredFilesLabel             matlab.ui.control.Label
        ObservationFile                matlab.ui.control.EditField
        ObservationFileLabel           matlab.ui.control.Label
        ObservationFileButton          matlab.ui.control.Button
        Sp3File                        matlab.ui.control.EditField
        Sp3FileButton                  matlab.ui.control.Button
        ClockFile                      matlab.ui.control.EditField
        ClockFileButton                matlab.ui.control.Button
        ClockFileIntervalEditField     matlab.ui.control.NumericEditField
        ClockFileIntervalEditFieldLabel  matlab.ui.control.Label
        SecLabel                       matlab.ui.control.Label
        BiasFile                       matlab.ui.control.EditField
        DCBFileLabel                   matlab.ui.control.Label
        BiasFileButton                 matlab.ui.control.Button
        AntennaFile                    matlab.ui.control.EditField
        AntennaFileEditFieldLabel      matlab.ui.control.Label
        AntennaFileButton              matlab.ui.control.Button
        ElevationAngle                 matlab.ui.control.NumericEditField
        DegreeLabel                    matlab.ui.control.Label
        ProcessingModeDropDown         matlab.ui.control.DropDown
        ProcessingModeDropDownLabel    matlab.ui.control.Label
        EpochIntervalsecondofdayLabel  matlab.ui.control.Label
        FromEditField                  matlab.ui.control.NumericEditField
        ToEditField                    matlab.ui.control.NumericEditField
        ToEditFieldLabel               matlab.ui.control.Label
        FromEditFieldLabel             matlab.ui.control.Label
        ElevationCutOffAngleLabel      matlab.ui.control.Label
        ObservationdayLabel            matlab.ui.control.Label
        ClockFileLabel                 matlab.ui.control.Label
        Model                          matlab.ui.container.Tab
        SatellitePanel                 matlab.ui.container.Panel
        ClockOffset                    matlab.ui.control.CheckBox
        MassCenter                     matlab.ui.control.CheckBox
        Windup                         matlab.ui.control.CheckBox
        ReceiverPanel                  matlab.ui.container.Panel
        APC                            matlab.ui.control.CheckBox
        ARP                            matlab.ui.control.CheckBox
        Atmosphere                     matlab.ui.container.Panel
        Ionosphere                     matlab.ui.control.CheckBox
        Troposphere                    matlab.ui.control.CheckBox
        OtherPanel                     matlab.ui.container.Panel
        RelClock                       matlab.ui.control.CheckBox
        RelPath                        matlab.ui.control.CheckBox
        SolidTide                      matlab.ui.control.CheckBox
        Filter                         matlab.ui.container.Tab
        InitialParameter               matlab.ui.container.Panel
        PosParameter                   matlab.ui.control.NumericEditField
        PosParameter2                  matlab.ui.control.NumericEditField
        PosLabel                       matlab.ui.control.Label
        RecClkParameter                matlab.ui.control.NumericEditField
        RecClkParameter2               matlab.ui.control.NumericEditField
        RecClkLabel2                   matlab.ui.control.Label
        TropParameter                  matlab.ui.control.NumericEditField
        TropParameter2                 matlab.ui.control.NumericEditField
        TropLabel                      matlab.ui.control.Label
        AmbParameter                   matlab.ui.control.NumericEditField
        AmbParameter2                  matlab.ui.control.NumericEditField
        PosParameter2_4Label           matlab.ui.control.Label
        AmbLabel                       matlab.ui.control.Label
        PosParameter2_3Label           matlab.ui.control.Label
        PosParameter2_2Label           matlab.ui.control.Label
        PosNoise2_2Label_2             matlab.ui.control.Label
        PhaseAmbiguitiesLabel          matlab.ui.control.Label
        TrophosphereLabel              matlab.ui.control.Label
        ReceiverClockLabel             matlab.ui.control.Label
        PositionEditFieldLabel         matlab.ui.control.Label
        MeasNoiseLabel                 matlab.ui.control.Label
        CodeMeas                       matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        PhaseMeas                      matlab.ui.control.NumericEditField
        PhaseMeasLabel                 matlab.ui.control.Label
        CodeMeasLabel                  matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        ProcessParametrer              matlab.ui.container.Panel
        PosNoise                       matlab.ui.control.NumericEditField
        PosNoise2                      matlab.ui.control.NumericEditField
        AmbNoise2_2Label_2             matlab.ui.control.Label
        PosNLabel                      matlab.ui.control.Label
        RecClkNoise                    matlab.ui.control.NumericEditField
        RecClkNoise2                   matlab.ui.control.NumericEditField
        RecClkNLabel                   matlab.ui.control.Label
        TropNoise                      matlab.ui.control.NumericEditField
        TropNoise2                     matlab.ui.control.NumericEditField
        TropNLabel                     matlab.ui.control.Label
        AmbNoise                       matlab.ui.control.NumericEditField
        AmbNoise2                      matlab.ui.control.NumericEditField
        AmbNLabel                      matlab.ui.control.Label
        PosNoise2_4Label               matlab.ui.control.Label
        PosNoise2_3Label               matlab.ui.control.Label
        PosNoise2_2Label               matlab.ui.control.Label
        AmbParameter_2Label            matlab.ui.control.Label
        TropParameter_2Label           matlab.ui.control.Label
        RecClkParameter_2Label         matlab.ui.control.Label
        PosParameter_2Label            matlab.ui.control.Label
        VR_CL                          matlab.ui.control.NumericEditField
        VarianceRatiosforMultiGNSSobservationsLabel  matlab.ui.control.Label
        VR_CP                          matlab.ui.control.NumericEditField
        GLabel                         matlab.ui.control.Label
        RLabel                         matlab.ui.control.Label
        ELabel                         matlab.ui.control.Label
        CLabel_2                       matlab.ui.control.Label
        VR_EL                          matlab.ui.control.NumericEditField
        VR_RL                          matlab.ui.control.NumericEditField
        VR_EP                          matlab.ui.control.NumericEditField
        VR_RP                          matlab.ui.control.NumericEditField
        VR_GL                          matlab.ui.control.NumericEditField
        PhaseMeasEditFieldLabel        matlab.ui.control.Label
        VR_GP                          matlab.ui.control.NumericEditField
        CodeMeasEditFieldLabel         matlab.ui.control.Label
        Output                         matlab.ui.container.Tab
        ImageInputLabel                matlab.ui.control.Label
        ImageInput                     matlab.ui.control.EditField
        ImageInputButton               matlab.ui.control.Button
        LALabel                        matlab.ui.control.Label
        LANorth                        matlab.ui.control.NumericEditField
        LANorthLabel                   matlab.ui.control.Label
        LAEast                         matlab.ui.control.NumericEditField
        LAEastLabel                    matlab.ui.control.Label
        LADown                         matlab.ui.control.NumericEditField
        LADownLabel                    matlab.ui.control.Label
        ZLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        NLabel                         matlab.ui.control.Label
        ObservationFileLabel_2         matlab.ui.control.Label
    end

    properties (Access = public)
        Data = struct(); % include data
        Options  = struct();
        Solution = struct();
        Import   = zeros(1);
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AntennaFileButton
        function AntennaFileButtonPushed(app, event)
           [file,path] = uigetfile('*.atx','Select the Antenna file');
           if ischar(file)
               anten = fullfile(path,file);
               app.AntennaFile.Editable    = 'on';
               app.AntennaFile.Value       = anten;
               app.AntennaFile.Editable    = 'off';
           else
               return
           end
        end

        % Callback function
        function AprioriPosDDValueChanged(app, event)
            value = app.AprioriPosDD.Value;
            if strcmp(value,'RINEX')
                app.LANorth.Editable = 'off';
                app.LAEast.Editable = 'off';
                app.LADown.Editable = 'off';
            elseif strcmp(value,'Specify')
                app.LANorth.Editable = 'on';
                app.LAEast.Editable = 'on';
                app.LADown.Editable = 'on';
            end 
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            xs  = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [n,e,u,ct,~,rms] = evaluate(xs,ref);
            app.AN.Editable = 'on';
            app.AN.Value    = n(end,1)*100;
            app.AN.Editable = 'off';
            app.AE.Editable = 'on';
            app.AE.Value    = e(end,1)*100;
            app.AE.Editable = 'off';
            app.AU.Editable = 'on';
            app.AU.Value    = u(end,1)*100;
            app.AU.Editable = 'off';
            app.RN.Editable = 'on';
            app.RN.Value    = rms(1,1)*100;
            app.RN.Editable = 'off';
            app.RE.Editable = 'on';
            app.RE.Value    = rms(2,1)*100;
            app.RE.Editable = 'off';
            app.RU.Editable = 'on';
            app.RU.Value    = rms(3,1)*100;
            app.RU.Editable = 'off';
            app.CT.Editable = 'on';
            app.CT.Value    = ct;
            app.CT.Editable = 'off';
        end

        % Button pushed function: ClockFileButton
        function ClockFileButtonPushed(app, event)
           [file,path] = uigetfile('*.clk*','Select the Clock file');
           if ischar(file)
               clock = fullfile(path,file);
               app.ClockFile.Editable      = 'on';
               app.ClockFile.Value         = clock;
               app.ClockFile.Editable      = 'off';
           else
               return
           end
        end

        % Callback function
        function ClockDropDownValueChanged(app, event)
            if strcmp(app.ClockDropDown.Value,'Clock File')
                app.ClockFile.Enable       = 'on';
                app.ClockFileButton.Enable = 'on';
                app.ClockFileIntervalEditField.Enable = 'on';
            elseif strcmp(app.ClockDropDown.Value,'Sp3 File')
                app.ClockFile.Editable     = 'on';
                app.ClockFile.Value        = '';
                app.ClockFileIntervalEditField.Enable = 'off';
                app.ClockFile.Editable     = 'off';
                app.ClockFile.Enable       = 'off';
                app.ClockFileButton.Enable = 'off';
            end
        end

        % Button pushed function: BiasFileButton
        function BiasFileButtonPushed(app, event)
            [file,path] = uigetfile('*.BIA','Select the BIA file.');
            if ischar(file)
                dcb = fullfile(path,file);
                app.BiasFile.Editable = 'on';
                app.BiasFile.Value    = dcb;
                app.BiasFile.Editable  = 'off';
            else
                return
            end
        end

        % Callback function
        function DCBDropDownValueChanged(app, event)
            if strcmp(app.DCBDropDown.Value,'P1 - C1')
                app.BiasFile.Enable       = 'on';
                app.BiasFileButton.Enable = 'on';
            elseif strcmp(app.DCBDropDown.Value,'None')
                app.BiasFile.Editable     = 'on';
                app.BiasFile.Value        = '';
                app.BiasFile.Editable     = 'off';
                app.BiasFile.Enable       = 'off';
                app.BiasFileButton.Enable = 'off';
            end
        end

        % Button pushed function: DefaultConfigurationButton
        function DefaultConfigurationButtonPushed(app, event)
            % INPUT
            % Observation File
            app.ObservationFile.Enable       = 'on';
            app.ObservationFile.Editable     = 'on';
            app.ObservationFile.Value        = '';
            app.ObservationFile.Editable     = 'off';
            app.ObservationFileButton.Enable = 'on';
            % Orbit Files
            app.Sp3File.Enable             = 'on';
            app.Sp3File.Editable           = 'on';
            app.Sp3File.Value              = '';
            app.Sp3File.Editable           = 'off';
            app.Sp3FileButton.Enable       = 'on';
            app.Orbit_B.Enable           = 'on';
            app.Orbit_B.Editable         = 'on';
            app.Orbit_B.Value            = '';
            app.Orbit_B.Editable         = 'off';
            app.OrbitButton_B.Enable     = 'on';
            app.Orbit_A.Enable           = 'on';
            app.Orbit_A.Editable         = 'on';
            app.Orbit_A.Value            = '';
            app.Orbit_A.Editable         = 'off';
            app.OrbitButton_A.Enable     = 'on';
            % Antenna Files
            app.AntennaFile.Enable           = 'on';
            app.AntennaFile.Editable         = 'on';
            app.AntennaFile.Value            = '';
            app.AntennaFile.Editable         = 'off';
            app.AntennaFileButton.Enable     = 'on';
            % Clock File
            app.ClockDropDown.Enable     = 'on';
            app.ClockDropDown.Value      = 'Clock File';
            app.ClockFile.Enable             = 'on';
            app.ClockFile.Editable           = 'on';
            app.ClockFile.Value              = '';
            app.ClockFile.Editable           = 'off';
            app.ClockFileButton.Enable       = 'on';
            app.ClockFileIntervalEditField.Value = 30;
            % DCB File
            app.DCBDropDown.Enable       = 'on';
            app.DCBDropDown.Value        = 'None';
            app.BiasFile.Enable               = 'off';
            app.BiasFile.Editable             = 'off';
            app.BiasFile.Value                = '';
            app.BiasFile.Editable             = 'off';
            app.BiasFileButton.Enable         = 'off';
            % System Selection
            app.GPSCheckBox.Value        = 1;
            app.GLONASSCheckBox.Value    = 0;
            app.GALILEOCheckBox.Value    = 0;
            app.BEIDOUCheckBox.Value     = 0;
            
            app.ImportButton.Enable      = 'on';
            app.SaveAsButton.Enable      = 'on';
            app.SavedFileCB.Value        = 0;
            app.SavedFile.Enable         = 'off';
            app.SavedFile.Value          = '';
            app.SavedFileButton.Enable   = 'off';
            
            % PREPROCESS
            app.ElevationAngle.Value           = 8;
            app.MWCycle.Value            = 1;
            app.GFCycle.Value            = 1;
            app.ClockJumpCB.Value        = 1;
            app.CodeSmoothCB.Value       = 0;
            app.StaticButton.Value       = 1;
            
            % MODEL
            app.ClockOffset.Value        =  1;
            app.MassCenter.Value         =  1;
            app.Windup.Value             =  1;
            app.APC.Value                =  1;
            app.ARP.Value                =  1;
            app.Troposphere.Value        =  1;
            app.TroGrad.Value           =  0;
            app.RelClock.Value           =  1;
            app.RelPath.Value            =  1;
            app.SolidTide.Value          =  1;
            
            % FILTER
            app.LSQR.Value               =  0;
            
            app.PosParameter.Editable    =  'on';
            app.PosParameter2.Editable   =  'on';
            app.RecClkParameter.Editable =  'on';
            app.RecClkParameter2.Editable=  'on';
            app.TropParameter.Editable   =  'on';
            app.TropParameter2.Editable  =  'on';
            app.AmbParameter.Editable    =  'on';
            app.AmbParameter2.Editable   =  'on';
            app.STDParameter.Editable    =  'on';
            app.STDParameter2.Editable   =  'on';
            
            app.PosParameter.Value       =  1;
            app.PosParameter2.Value      =  2;
            app.RecClkParameter.Value    =  1;
            app.RecClkParameter2.Value   =  5;
            app.TropParameter.Value      =  0.5;
            app.TropParameter2.Value     =  0;
            app.AmbParameter.Value       =  2;
            app.AmbParameter2.Value      =  1;
            app.STDParameter.Value       =  1;
            app.STDParameter2.Value      =  2;
            
            app.PosNoise.Value           = 0;
            app.PosNoise2.Value          = 0;
            app.PosNoise.Editable        = 'off';
            app.PosNoise2.Editable       = 'off';
            app.RecClkNoise.Value        =  1;
            app.RecClkNoise2.Value       =  5;
            app.TropNoise.Value          =  1;
            app.TropNoise2.Value         = -9;
            app.STDNoise.Value           =  1;
            app.STDNoise2.Value          = -7;
            
            app.AprioriPosDD.Enable      = 'on';
            app.AprioriPosDD.Value       = 'RINEX';
            app.LANorth.Editable        =  'Off';
            app.LAEast.Editable        =  'Off';
            app.LADown.Editable        =  'Off';
            app.LANorth.Value           =  0;
            app.LAEast.Value           =  0;
            app.LADown.Value           =  0;
            
            app.MeasWeight.Value         = 'Elevation Dependent';
            app.CodeMeas.Value           = 3;
            app.PhaseMeas.Value          = 0.003;
            
            % GENERAL
            app.FromEditField.Value      =  0;
            app.ToEditField.Value        = 86370;
        end

        % Callback function
        function HORButtonPushed(app, event)
            xs = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [n,e,~,~,~,~] = evaluate(xs,ref);
            
            % boundaries
            % st = app.PFrom.Value/30 + 1;
            % fn = app.PTo.Value/30 + 1;
            % t = st:fn;
            % t = (t-1)./120; % hour
            
            fig = figure('Name','Horizontal Positioning Errors','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(n(:,1),e(:,1),'*')
            ax.Title.String = 'Horizontal Positioning Errors';
            ax.XLabel.String = 'North Error (Meter)';
            ax.YLabel.String = 'East Error (Meter)';
            ax.XAxisLocation = 'origin';
            ax.YAxisLocation = 'origin';
            ax.XLim = [-1 1];
            ax.YLim = [-1 1];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
        end

        % Callback function
        function ImportButtonPushed(app, event)
            try
                f = waitbar(0.10, {'Please wait...' 'Operation is processing...'});
                if isempty(app.ObservationFile.Value)
                    errordlg('Please select a valid observation file','File error');
                elseif isempty(app.Sp3File.Value)
                    errordlg('Please select a valid orbit file','File error'); 
                elseif isempty(app.AntennaFile.Value)
                    errordlg('Please select a valid antenna file','File error');
                elseif strcmp(app.ClockDropDown.Value,'Clock File') && isempty(app.ClockFile.Value)
                    errordlg('Please select a valid clock file','File error');
                elseif strcmp(app.DCBDropDown.Value,'P1 - C1') && isempty(app.BiasFile.Value)
                    errordlg('Please select a valid DCB file','File error');
                elseif app.GPSCheckBox.Value == 0 && app.GLONASSCheckBox.Value == 0 && app.GALILEOCheckBox.Value == 0
                    errordlg('GPS or GLONASS have to be selected.','System selection error');
                else
                    % files
                    files.rinex  = app.ObservationFile.Value;
                    files.orbitb = app.Orbit_B.Value;
                    files.orbit  = app.Sp3File.Value;
                    files.orbita = app.Orbit_A.Value;
                    files.anten  = app.AntennaFile.Value;
                    files.clock  = app.ClockFile.Value;
                    files.dcb    = app.BiasFile.Value;
                    % options
                    options.clock      = app.ClockDropDown.Value;
                    options.system.gps = app.GPSCheckBox.Value;
                    options.system.glo = app.GLONASSCheckBox.Value;
                    options.system.gal = app.GALILEOCheckBox.Value;
                    options.system.bds = app.BEIDOUCheckBox.Value;
                    options.from       = app.FromEditField.Value;
                    options.to         = app.ToEditField.Value;
                    if ~isempty(app.BiasFile.Value)
                        options.dcb = 1;
                    else
                        options.dcb = 0;
                    end
                    options.clck_int   = app.ClockFileIntervalEditField.Value;
                    % read the files
                    [ppps] = data_hand(files,options);
                    
                    waitbar(0.15,f)
                    
                    app.Data = ppps;
                    % Specify the epoch time
                    app.FromEditField.Value = ppps.opt.from;
                    app.ToEditField.Value = ppps.opt.to;
                    % Specify the plot time
                    app.PFrom.Value = ppps.obs.ep(1,1);
                    app.PTo.Value = ppps.obs.ep(end,1);
                    
                    msgbox('Import operation successfully completed');
                    app.Import = 1;
                    
                    close(f)
                end
            catch ME
                if any(ME.identifier)
                    errordlg('Please check the files.','File Error');
                end
            end
        end

        % Callback function
        function LSQRValueChanged(app, event)
            value = app.LSQR.Value;
            if value == 1
                app.PosParameter.Editable     = 'off';
                app.PosParameter2.Editable    = 'off';
                app.RecClkParameter.Editable  = 'off';
                app.RecClkParameter2.Editable = 'off';
                app.TropParameter.Editable    = 'off';
                app.TropParameter2.Editable   = 'off';
                app.AmbParameter.Editable     = 'off';
                app.AmbParameter2.Editable    = 'off';
                app.STDParameter.Editable     = 'off';
                app.STDParameter2.Editable    = 'off';
                app.AprioriPosDD.Enable       = 'off';
                if strcmp(app.AprioriPosDD.Value,'Specify')
                    app.LANorth.Value        =  0;
                    app.LANorth.Editable     = 'off';
                    app.LAEast.Value        =  0;
                    app.LAEast.Editable     = 'off';
                    app.LADown.Value        =  0;
                    app.LADown.Editable     = 'off';
                end
            elseif value == 0
                app.PosParameter.Editable     = 'on';
                app.PosParameter2.Editable    = 'on';
                app.RecClkParameter.Editable  = 'on';
                app.RecClkParameter2.Editable = 'on';
                app.TropParameter.Editable    = 'on';
                app.TropParameter2.Editable   = 'on';
                app.AmbParameter.Editable     = 'on';
                app.AmbParameter2.Editable    = 'on';
                app.STDParameter.Editable     = 'on';
                app.STDParameter2.Editable    = 'on';
                app.AprioriPosDD.Enable       = 'on';
                if strcmp(app.AprioriPosDD.Value,'Specify')
                    app.LANorth.Editable         = 'on';
                    app.LAEast.Editable         = 'on';
                    app.LADown.Editable         = 'on';
                end
            end
        end

        % Button pushed function: LoadSavedConfigurationButton
        function LoadSavedConfigurationButtonPushed(app, event)
            [file,path] = uigetfile('*.mat','Select the Previously Saved Data file');
            
            if ischar(file)
                Option = fullfile(path,file);
                load(Option)
                % INPUT
                app.GPSCheckBox.Value      = Option.GPSSelect;
                app.GLONASSCheckBox.Value  = Option.GLONASSSelect; 
                app.GALILEOCheckBox.Value  = Option.GALILEOSelect;
                app.BEIDOUCheckBox.Value   = Option.BEIDOUSelect;
                app.ClockDropDown.Value    = Option.ClockSelect;
                if strcmp(app.ClockDropDown.Value,'Clock File')
                    app.ClockFile.Enable = 'on';
                    app.ClockFileButton.Enable = 'on';
                else
                    app.ClockFile.Enable = 'off';
                    app.ClockFileButton.Enable = 'off';
                end
                app.ClockFileIntervalEditField.Value = Option.ClockInt;
                app.DCBDropDown.Value      = Option.DCBSelect;
                if strcmp(app.DCBDropDown.Value,'P1 - C1')
                    app.BiasFile.Enable = 'on';
                    app.BiasFileButton.Enable = 'on';
                else
                    app.BiasFile.Enable = 'off';
                    app.BiasFileButton.Enable = 'off';
                end
                % PREPROCESS
                app.ElevationAngle.Value         = Option.ElvAngle;
                app.MWCycle.Value          = Option.CSMw;
                app.GFCycle.Value          = Option.CSGf;
                app.ClockJumpCB.Value      = Option.ClkJump;
                app.CodeSmoothCB.Value     = Option.CodSmth;
                app.StaticButton.Value     = Option.Static;
                app.KinematicButton.Value  = Option.Kinematic;
                
                % MODELLING
                app.ClockOffset.Value      = Option.SatClk;
                app.MassCenter.Value       = Option.SatAPC;
                app.Windup.Value           = Option.SatWind;
                app.APC.Value              = Option.RecAPC;
                app.ARP.Value              = Option.RecARP;
                app.Troposphere.Value      = Option.AtmTrop;
                if app.Troposphere.Value == 1
                    app.TroGrad.Enable = 'on';
                    app.TroGrad.Value = Option.TroGrad;
                elseif app.Troposphere.Value == 0
                    app.TroGrad.Enable = 'off';
                    app.TroGrad.Value = 0;
                end
                app.RelClock.Value         = Option.RelClk;
                app.RelPath.Value          = Option.RelPath;
                app.SolidTide.Value        = Option.Solid;
                
                % filter
                app.LSQR.Value             = Option.InMethod;
                
                if app.LSQR.Value == 0
                    app.PosParameter.Editable     = 'on';
                    app.PosParameter2.Editable    = 'on';
                    app.RecClkParameter.Editable  = 'on';
                    app.RecClkParameter2.Editable = 'on';
                    app.TropParameter.Editable    = 'on';
                    app.TropParameter2.Editable   = 'on';
                    app.AmbParameter.Editable     = 'on';
                    app.AmbParameter2.Editable    = 'on';
                    app.STDParameter.Editable     = 'on';
                    app.STDParameter2.Editable    = 'on';
                else
                    app.PosParameter.Editable     = 'off';
                    app.PosParameter2.Editable    = 'off';
                    app.RecClkParameter.Editable  = 'off';
                    app.RecClkParameter2.Editable = 'off';
                    app.TropParameter.Editable    = 'off';
                    app.TropParameter2.Editable   = 'off';
                    app.AmbParameter.Editable     = 'off';
                    app.AmbParameter2.Editable    = 'off';
                    app.STDParameter.Editable     = 'off';
                    app.STDParameter2.Editable    = 'off';
                end
                    
                app.PosParameter.Value     = Option.PosParameter;
                app.PosParameter2.Value    = Option.PosParameter2;
                app.RecClkParameter.Value  = Option.RecClkParameter;
                app.RecClkParameter2.Value = Option.RecClkParameter2;
                app.TropParameter.Value    = Option.TropParameter;
                app.TropParameter2.Value   = Option.TropParameter2;
                app.AmbParameter.Value     = Option.AmbParameter;
                app.AmbParameter2.Value    = Option.AmbParameter2;
                app.STDParameter.Value     = Option.STDParameter;
                app.STDParameter2.Value    = Option.STDParameter2;
                
                if app.StaticButton.Value == 1
                    app.PosNoise.Editable  = 'off';
                    app.PosNoise2.Editable = 'off';
                else
                    app.PosNoise.Editable  = 'on';
                    app.PosNoise2.Editable = 'on';
                end
                
                app.PosNoise.Value         = Option.NosPos;
                app.PosNoise2.Value        = Option.NosPos2;
                app.RecClkNoise.Value      = Option.NosClk;
                app.RecClkNoise2.Value     = Option.NosClk2;
                app.TropNoise.Value        = Option.NosTrop;
                app.TropNoise2.Value       = Option.NosTrop2;
                app.STDNoise.Value         = Option.NosSTD;
                app.STDNoise2.Value        = Option.NosSTD2;
                
                app.AprioriPosDD.Value     = Option.ApMethod;
                if strcmp(app.AprioriPosDD.Value,'RINEX')
                    app.LANorth.Editable      =  'off';
                    app.LAEast.Editable      =  'off';
                    app.LADown.Editable      =  'off';
                else
                    app.LANorth.Editable      =  'on';
                    app.LAEast.Editable      =  'on';
                    app.LADown.Editable      =  'on';
                end
                app.LANorth.Value         = Option.AprioriX;
                app.LAEast.Value         = Option.AprioriY;
                app.LADown.Value         = Option.AprioriZ;
                
                app.MeasWeight.Value       = Option.WeMethod;
                app.CodeMeas.Value         = Option.CodeStd;
                app.PhaseMeas.Value        = Option.PhaseStd;
                % general
                app.FromEditField.Value    = Option.From ;
                app.ToEditField.Value      = Option.To ;
            end
        end

        % Callback function
        function MeasurementDropDownValueChanged(app, event)
            value = app.MeasurementDropDown.Value;
            if strcmp(value,'Static')
                app.PosNoise.Value     = 0;
                app.PosNoise2.Value    = 0;
                
                app.PosNoise.Editable  = 'off';
                app.PosNoise2.Editable = 'off';
            elseif strcmp(value,'Kinematic')
                app.PosNoise.Editable  = 'on';
                app.PosNoise2.Editable = 'on';
            end
        end

        % Callback function
        function NEUButtonPushed(app, event)
            xs = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [n,e,u,~,~,~] = evaluate(xs,ref);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','NEU Positioning Errors','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(ax,t,n(:,1),t,e(:,1),t,u(:,1))
            ax.Title.String = 'NEU Positioning Errors';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Error (Meter)';
            min1 = t(1) - ((t(end)-t(1))*0.1);
            max1 = t(end);
            ax.XLim = [min1 max1];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'North','East','Up')
        end

        % Button pushed function: ObservationFileButton
        function ObservationFileButtonPushed(app, event)
           [file,path] = uigetfile({'*.**o';'*.rnx';'*.obs'},'Select the Observation file');
           if ischar(file)
               rinex = fullfile(path,file);
               app.ObservationFile.Editable = 'on';
               app.ObservationFile.Value    = rinex;
               app.ObservationFile.Editable = 'off';
           else
               return
           end
           
        end

        % Button pushed function: Sp3FileButton
        function Sp3FileButtonPushed(app, event)
           [file,path] = uigetfile('*.sp3','Select the Orbit file');
           if ischar(file)
               orbit = fullfile(path,file);
               app.Sp3File.Editable       = 'on';
               app.Sp3File.Value          = orbit;
               app.Sp3File.Editable       = 'off';
           else
               return
           end
        end

        % Callback function
        function PDOPButtonPushed(app, event)
            kof = app.Solution.kof;
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            gdop = zeros(size(t,1),1);
            pdop = zeros(size(t,1),1);
            tdop = zeros(size(t,1),1);
            
            n = 0;
            for i=1:size(kof,3)
                n = n+1;
                AK = kof(:,:,i);
                gdop(n,1) = sqrt(AK(1,1) + AK(2,2) + AK(3,3) + AK(4,4));
                pdop(n,1) = sqrt(AK(1,1) + AK(2,2) + AK(3,3));
                tdop(n,1) = sqrt(AK(4,4));
            end
            
            fig = figure('Name','Dilution of Precision','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,gdop,t,pdop,t,tdop)
            ax.Title.String = 'Diluton of Precision';
            ax.XLabel.String = 'Time (Hour)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'GDOP','PDOP','TDOP')
        end

        % Callback function
        function RecClkEstButtonPushed(app, event)
            xs = app.Solution.xs;
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Receiver Clock Estimation','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,xs(4,:))
            ax.Title.String = 'Receiver Clock Estimation';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Receiver Clock (Meter)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Receiver Clock')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                f = waitbar(0.15, {'Please wait...' 'Operation is processing...'}, 'Name','PPPH-UAV');
                % control the required files
                if isempty(app.ObservationFile.Value)
                    errordlg('Please select a valid observation file','File error');
                elseif isempty(app.Sp3File.Value)
                    errordlg('Please select a valid orbit file','File error'); 
                elseif isempty(app.ClockFile.Value)
                    errordlg('Please select a valid clock file','File error');
                elseif isempty(app.BiasFile.Value)
                    errordlg('Please select a valid bias file','File error');
                elseif isempty(app.AntennaFile.Value)
                    errordlg('Please select a valid antenna file','File error');
                else
                    % files definition
                    files.rnx = app.ObservationFile.Value;
                    files.sp3 = app.Sp3File.Value;
                    files.clk = app.ClockFile.Value;
                    files.atx = app.AntennaFile.Value;
                    files.osb = app.BiasFile.Value;
                    % options definition
                    options.clkint = app.ClockFileIntervalEditField.Value;
                    options.elvang = app.ElevationAngle.Value;
                    
                    if strcmp(app.ProcessingModeDropDown.Value,'Static')
                        options.ProMod = 0;
                    elseif strcmp(app.ProcessingModeDropDown.Value,'Kinematic')
                        options.ProMod = 1;
                    end

                    options.SatClk  = app.ClockOffset.Value;
                    options.SatAPC  = app.MassCenter.Value;
                    options.SatWind = app.Windup.Value;

                    options.RecAPC  = app.APC.Value;
                    options.RecARP  = app.ARP.Value;

                    options.AtmTrop = app.Troposphere.Value;

                    options.RelClk  = app.RelClock.Value;
                    options.RelPath = app.RelPath.Value;
                    options.Solid   = app.SolidTide.Value;


                    options.IntPos   = app.PosParameter.Value;
                    options.IntPos2  = app.PosParameter2.Value;
                    options.IntClk   = app.RecClkParameter.Value;
                    options.IntClk2  = app.RecClkParameter2.Value;
                    options.IntTrop  = app.TropParameter.Value;
                    options.IntTrop2 = app.TropParameter2.Value;
                    options.IntAmb   = app.AmbParameter.Value;
                    options.IntAmb2  = app.AmbParameter2.Value;
                    
                    options.NosPos   = app.PosNoise.Value;
                    options.NosPos2  = app.PosNoise2.Value;
                    options.NosClk   = app.RecClkNoise.Value;
                    options.NosClk2  = app.RecClkNoise2.Value;
                    options.NosTrop  = app.TropNoise.Value;
                    options.NosTrop2 = app.TropNoise2.Value;
                    
                    options.CodeStd  = app.CodeMeas.Value;
                    options.PhaseStd = app.PhaseMeas.Value;

                    options.vrt.gps.p = app.VR_GP.Value;  options.vrt.gps.l = app.VR_GL.Value;
                    options.vrt.glo.p = app.VR_RP.Value;  options.vrt.glo.l = app.VR_RL.Value;
                    options.vrt.gal.p = app.VR_EP.Value;  options.vrt.gal.l = app.VR_EL.Value;
                    options.vrt.bds.p = app.VR_CP.Value;  options.vrt.bds.l = app.VR_CL.Value;

                    % Output files
                    input_filename = app.ImageInput.Value;
                    offsetBody = [app.LANorth.Value,app.LAEast.Value,app.LADown.Value];
                    output_filename = 'output.txt';


                    % data handling
                    waitbar(0.2, f, {'Please wait...' 'Reading data...'})
                    [data,options] = datahandling(files,options);
                    % preprocessing
                    waitbar(0.3, f, {'Please wait...' 'Pre processing data...'})
                    [data] = preprocessing(data,options);
                    % model & filtering
                    waitbar(0.35,f,{'Please wait...' 'Modeling and filtering...'})
                    [~,~,~,~,~,fxpos,~] = modelandfiltering(data, options, f, 0.35, 0.95);

                    waitbar(0.95,f,{'Please wait...' sprintf('Creating results... (%.2f%%)', 0.95*100)})
                    % output
                    pppar_sol = process_PPPAR_solutions(input_filename, data, fxpos, offsetBody, output_filename);
                    
                    msgbox('Output file successfully created.');
                    
                    close(f)
                end
            catch ME
                if any(ME.identifier)
                    errordlg('Please check the files.','File Error');
                end
            end

            % try
            %     f = waitbar(0.40, {'Please wait...' 'Operation is processing'});
            %     if app.Import ~= 1
            %         errordlg('You have to import your files before push run button','Import Error');
            %     else
            %         % from files
            %         data = app.Data;
            %         % from options
            % 
            %         % preprocess
            %         Option.elvangle = app.ElevationAngle.Value;
            %         Option.CSMw     = app.MWCycle.Value;
            %         Option.CSGf     = app.GFCycle.Value;
            %         Option.clkjump  = app.ClockJumpCB.Value;
            %         Option.codsmth  = app.CodeSmoothCB.Value;
            %         if app.StaticButton.Value == 1
            %             Option.ProMod = 1; %static
            %         else
            %             Option.ProMod = 0; %kinematic
            %         end
            % 
            %         % model
            %         Option.SatClk   = app.ClockOffset.Value;
            %         Option.SatAPC   = app.MassCenter.Value;
            %         Option.SatWind  = app.Windup.Value;
            %         Option.RecAPC   = app.APC.Value;
            %         Option.RecARP   = app.ARP.Value;
            % 
            %         Option.RelClk   = app.RelClock.Value;
            %         Option.RelPath  = app.RelPath.Value;
            %         Option.Solid    = app.SolidTide.Value;
            % 
            %         % troposphere
            %         Option.AtmTrop  = app.Troposphere.Value;
            %         Option.TroGrad  = app.TroGrad.Value;
            %         if app.SaastamoinenButton.Value == 1
            %             Option.TropMod = 'Saastamoinen';
            %         elseif app.ModifiedHopfieldButton.Value == 1
            %             Option.TropMod = 'Hopfield';
            %         elseif app.VMF1Button.Value == 1
            %             Option.TropMod = 'VMF1';
            %         elseif app.VMF3Button.Value == 1
            %             Option.TropMod = 'VMF3';
            %         end
            % 
            %         if app.GPT2Button.Value==1
            %             Option.Meteo = 'GPT2';
            %         elseif app.GPT3Button.Value == 1
            %             Option.Meteo = 'GPT3';
            %         elseif app.InsituMeasurementButton.Value==1
            %             Option.Meteo = 'MetFile';
            %             data.files.meteo = app.MeteoFile.Value;
            %         end
            % 
            %         if app.GMFButton.Value == 1
            %             Option.MapFunction = 'GMF';
            %         elseif app.VMF1Button2.Value == 1
            %             Option.MapFunction = 'VMF1';
            %         elseif app.VMF3Button2.Value == 1
            %             Option.MapFunction = 'VMF3';
            %         end
            % 
            %         % filter
            %         Option.InMethod = app.LSQR.Value;
            %         if app.LSQR.Value == 0
            %             Option.IntPos    = app.PosParameter.Value;
            %             Option.IntPos2   = app.PosParameter2.Value;
            %             Option.IntClk    = app.RecClkParameter.Value;
            %             Option.IntClk2   = app.RecClkParameter2.Value;
            %             Option.IntTrop   = app.TropParameter.Value;
            %             Option.IntTrop2  = app.TropParameter2.Value;
            %             Option.IntSTD    = app.STDParameter.Value;
            %             Option.IntSTD2   = app.STDParameter2.Value;
            %         end
            %         Option.IntAmb    = app.AmbParameter.Value;
            %         Option.IntAmb2   = app.AmbParameter2.Value;
            % 
            %         Option.NosPos   = app.PosNoise.Value;
            %         Option.NosPos2  = app.PosNoise2.Value;
            %         Option.NosClk   = app.RecClkNoise.Value;
            %         Option.NosClk2  = app.RecClkNoise2.Value;
            %         Option.NosTrop  = app.TropNoise.Value;
            %         Option.NosTrop2 = app.TropNoise2.Value;
            %         Option.NosSTD   = app.STDNoise.Value;
            %         Option.NosSTD2  = app.STDNoise2.Value;
            % 
            %         Option.ApMethod = app.AprioriPosDD.Value;
            %         if strcmp(app.AprioriPosDD.Value,'Specify')
            %             Option.AprioriX = app.LANorth.Value; 
            %             Option.AprioriY = app.LAEast.Value;
            %             Option.AprioriZ = app.LADown.Value;
            %         end
            % 
            %         Option.WeMethod = app.MeasWeight.Value;
            %         Option.CodeStd  = app.CodeMeas.Value;
            %         Option.PhaseStd = app.PhaseMeas.Value;
            % 
            %         Option.vrat.gps.p = app.VR_GP.Value;  Option.vrat.gps.l = app.VR_GL.Value;
            %         Option.vrat.glo.p = app.VR_RP.Value;  Option.vrat.glo.l = app.VR_RL.Value;
            %         Option.vrat.gal.p = app.VR_EP.Value;  Option.vrat.gal.l = app.VR_EL.Value;
            %         Option.vrat.bds.p = app.VR_CP.Value;  Option.vrat.bds.l = app.VR_CL.Value;
            % 
            %         Option.VCE = app.VCE.Value;
            % 
            %         % general
            %         Option.from     = app.FromEditField.Value;
            %         Option.to       = app.ToEditField.Value;
            % 
            %         Option.system.gps = data.opt.system.gps;
            %         Option.system.glo = data.opt.system.glo;
            %         Option.system.gal = data.opt.system.gal;
            %         Option.system.bds = data.opt.system.bds;
            % 
            %         % preprocess
            %         [data] = preprocess(data,Option);
            %         waitbar(0.4,f)
            %         % modelling
            %         [model] = nmodel(data,Option);
            %         waitbar(0.65,f)
            %         % filtering
            %         [xs,kof,~] = MGNSS_filter(model,data,Option);
            %         waitbar(0.9,f)
            %         [TzD] = total_trop(data,model,xs);
            % 
            %         app.Solution.xs = xs;
            %         app.Solution.kof = kof;
            %         app.Solution.model = model;
            %         app.Solution.TZD = TzD;
            % 
            %         % filename = strcat(app.ObservationFile.Value(1:4),app.Sp3File.Value(4:8),'.txt');
            %         [file,path] = uiputfile('*.txt','Save File As');
            %         filename = fullfile(path,file);
            %         % dlmwrite(filename,xs','delimiter','\t','precision','%.3f')
            %         out_write(xs,Option,model,filename,data);
            %         msgbox('Operation Successfully Completed');
            %         app.NEU.Enable  = 'on';
            %         app.CalculateButton.Enable  = 'on';
            %         app.HOR.Enable  = 'on';
            %         app.SNO.Enable  = 'on';
            %         app.PDOP.Enable  = 'on';
            %         app.ThrDPos.Enable = 'on';
            %         app.RecClkEst.Enable = 'on';
            %         app.TZD.Enable = 'on';
            %         app.PWV.Enable = 'on';
            % 
            %         close(f)
            %     end
            % catch ME
            %     if any(ME.identifier)
            %         errordlg('Please check your files','Run Error');
            %     end 
            % end
        end

        % Callback function
        function SNOButtonPushed(app, event)
            data = app.Data;
            [satno] = dtr_satno(data.obs);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Visible Satellite Numbers','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,satno(st:fn,1))
            ax.Title.String = 'Visible Satellite Numbers';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Satellite Number';
            ax.YLim = [0 max(satno(st:fn,1))+5];
            ax.YTick = 0:2:max(satno)+5;
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Satellite Number')
        end

        % Callback function
        function SaveAsButtonPushed(app, event)
            try
                if app.Import == 1
                    data = app.Data;
                    uisave({'data'});
                else
                    errordlg('You have to import your files before push save button','Import Error');
                end
            catch ME
                if any(ME.identifier)
                    errordlg('Please import your files','Import Error');
                end
            end
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            % INPUT
            Option.GPSSelect        = app.GPSCheckBox.Value;
            Option.GLONASSSelect    = app.GLONASSCheckBox.Value;
            Option.GALILEOSelect    = app.GALILEOCheckBox.Value;
            Option.BEIDOUSelect     = app.BEIDOUCheckBox.Value;
            Option.ClockSelect      = app.ClockDropDown.Value;
            Option.ClockInt         = app.ClockFileIntervalEditField.Value;
            Option.DCBSelect        = app.DCBDropDown.Value;
            % PREPROCESS
            Option.ElvAngle         = app.ElevationAngle.Value;
            Option.CSMw             = app.MWCycle.Value;
            Option.CSGf             = app.GFCycle.Value;
            Option.ClkJump          = app.ClockJumpCB.Value;
            Option.CodSmth          = app.CodeSmoothCB.Value;
            Option.Static           = app.StaticButton.Value;
            Option.Kinematic        = app.KinematicButton.Value;
            % MODELLING
            Option.SatClk           = app.ClockOffset.Value;
            Option.SatAPC           = app.MassCenter.Value;
            Option.SatWind          = app.Windup.Value;
            Option.RecAPC           = app.APC.Value;
            Option.RecARP           = app.ARP.Value;
            Option.AtmTrop          = app.Troposphere.Value;
            Option.TroGrad         = app.TroGrad.Value;
            Option.RelClk           = app.RelClock.Value;
            Option.RelPath          = app.RelPath.Value;
            Option.Solid            = app.SolidTide.Value;
            % filter
            Option.InMethod         = app.LSQR.Value;
            
            Option.PosParameter     = app.PosParameter.Value;
            Option.PosParameter2    = app.PosParameter2.Value;
            Option.RecClkParameter  = app.RecClkParameter.Value;
            Option.RecClkParameter2 = app.RecClkParameter2.Value;
            Option.TropParameter    = app.TropParameter.Value;
            Option.TropParameter2   = app.TropParameter2.Value;
            Option.AmbParameter     = app.AmbParameter.Value;
            Option.AmbParameter2    = app.AmbParameter2.Value;
            Option.STDParameter     = app.STDParameter.Value;
            Option.STDParameter2    = app.STDParameter2.Value;
            
            Option.NosPos           = app.PosNoise.Value;
            Option.NosPos2          = app.PosNoise2.Value;
            Option.NosClk           = app.RecClkNoise.Value;
            Option.NosClk2          = app.RecClkNoise2.Value;
            Option.NosTrop          = app.TropNoise.Value;
            Option.NosTrop2         = app.TropNoise2.Value;
            Option.NosSTD           = app.STDNoise.Value;
            Option.NosSTD2          = app.STDNoise2.Value;
            
            Option.ApMethod         = app.AprioriPosDD.Value;
            Option.AprioriX         = app.LANorth.Value;
            Option.AprioriY         = app.LAEast.Value;
            Option.AprioriZ         = app.LADown.Value;
            
            Option.WeMethod         = app.MeasWeight.Value;
            Option.CodeStd          = app.CodeMeas.Value;
            Option.PhaseStd         = app.PhaseMeas.Value;
            % GENERAL
            Option.From             = app.FromEditField.Value;
            Option.To               = app.ToEditField.Value;
            
            uisave({'Option'});
        end

        % Callback function
        function SavedFileButtonPushed(app, event)
           [file,path] = uigetfile('*.mat','Select the Previously Saved Data file');
           if ischar(file)
               ppps = fullfile(path,file);
               app.SavedFile.Editable = 'on';
               app.SavedFile.Value    = ppps;
               app.SavedFile.Editable = 'off';
               load(ppps);
               app.Data = data(:);
               app.Import = 1;
           else
               return
           end
        end

        % Callback function
        function SavedFileCBValueChanged(app, event)
            value = app.SavedFileCB.Value;
            if value == 1
                app.ObservationFile.Enable       = 'off';
                app.ObservationFileButton.Enable = 'off';
                app.Sp3File.Enable             = 'off';
                app.Sp3FileButton.Enable       = 'off';
                app.Orbit_B.Enable           = 'off';
                app.OrbitButton_B.Enable     = 'off';
                app.Orbit_A.Enable           = 'off';
                app.OrbitButton_A.Enable     = 'off';
                app.ClockDropDown.Enable     = 'off';
                app.ClockFile.Enable             = 'off';
                app.ClockFileButton.Enable       = 'off';
                app.AntennaFile.Enable           = 'off';
                app.AntennaFileButton.Enable     = 'off';
                app.ImportButton.Enable      = 'off';
                app.SaveAsButton.Enable      = 'off';
                app.DCBDropDown.Enable       = 'off';
                app.BiasFileButton.Enable         = 'off';
                app.BiasFile.Enable               = 'off';
                app.ClockFileIntervalEditField.Enable = 'off';
                app.GPSCheckBox.Enable       = 'off';
                app.GLONASSCheckBox.Enable   = 'off';
                app.GALILEOCheckBox.Enable   = 'off';
                app.BEIDOUCheckBox.Enable    = 'off';
                
                app.SavedFile.Enable         = 'on';
                app.SavedFileButton.Enable   = 'on';
            elseif value == 0
                app.ObservationFile.Enable       = 'on';
                app.ObservationFileButton.Enable = 'on';
                app.Sp3File.Enable             = 'on';
                app.Sp3FileButton.Enable       = 'on';
                app.Orbit_B.Enable           = 'on';
                app.OrbitButton_B.Enable     = 'on';
                app.Orbit_A.Enable           = 'on';
                app.OrbitButton_A.Enable     = 'on';
                app.ClockDropDown.Enable     = 'on';
                if strcmp(app.ClockDropDown.Value,'Clock File')
                    app.ClockFileButton.Enable   = 'on';
                    app.ClockFile.Enable         = 'on';
                end
                app.AntennaFile.Enable           = 'on';
                app.AntennaFileButton.Enable     = 'on';
                app.ImportButton.Enable      = 'on';
                app.SaveAsButton.Enable      = 'on';
                app.DCBDropDown.Enable       = 'on';
                if strcmp(app.DCBDropDown.Value,'P1 - C1')
                    app.BiasFileButton.Enable     = 'on';
                    app.BiasFile.Enable           = 'on';
                end
                app.ClockFileIntervalEditField.Enable = 'on';
                app.GPSCheckBox.Enable       = 'on';
                app.GLONASSCheckBox.Enable   = 'on';
                app.GALILEOCheckBox.Enable   = 'on';
                app.BEIDOUCheckBox.Enable    = 'on';
                
                app.SavedFile.Enable         = 'off';
                app.SavedFileButton.Enable   = 'off';
            end
        end

        % Callback function
        function ThrDPosButtonPushed(app, event)
            xs = app.Solution.xs;
            ref = [app.refX.Value;app.refY.Value;app.refZ.Value];
            [~,~,~,~,ThrD,~] = evaluate(xs,ref);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','3D Positioning Errors','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,ThrD(:,1))
            ax.Title.String = '3D Positioning Errors';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Error (Meter)';
            td = (t(end) - t(1))*0.1;
            min1 = t(1)-td;
            max1 = t(end)+td;
            ax.XLim = [min1 max1];
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'3D Positioning Error')
        end

        % Callback function
        function OrbitButton_BPushed(app, event)
           [file,path] = uigetfile('*.sp3','Select the Orbit file');
           if ischar(file)
               orbitb = fullfile(path,file);
               app.Orbit_B.Editable       = 'on';
               app.Orbit_B.Value          = orbitb;
               app.Orbit_B.Editable       = 'off';
           else
               return
           end
        end

        % Callback function
        function OrbitButton_APushed(app, event)
           [file,path] = uigetfile('*.sp3','Select the Orbit file');
           if ischar(file)
               orbita = fullfile(path,file);
               app.Orbit_A.Editable       = 'on';
               app.Orbit_A.Value          = orbita;
               app.Orbit_A.Editable       = 'off';
           else
               return
           end
        end

        % Callback function
        function ProcessingModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProcessingModeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Kinematic')
                app.PosNoise.Editable = 'on';
                app.PosNoise2.Editable = 'on';
            elseif strcmp(selectedButton.Text,'Static')
                app.PosNoise.Value = 0;
                app.PosNoise.Editable = 'off';
                app.PosNoise2.Value = 0;
                app.PosNoise2.Editable = 'off';
            end
        end

        % Value changed function: Troposphere
        function TroposphereValueChanged(app, event)
            value = app.Troposphere.Value;
            if value == 1
                app.TroGrad.Enable = 'on';
            else
                app.TroGrad.Value  = 0;
                app.TroGrad.Enable = 'off';
            end
        end

        % Callback function
        function TZDButtonPushed(app, event)
            xs = app.Solution.xs;
            model = app.Solution.model;
            tzd = model(1,31) + xs(5,:)';
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Tropospheric Zenith Total Delay','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,tzd)
            ax.Title.String = 'Tropospheric Zenith Total Delay';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Zenith Total Delay (Meter)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Zenith Total Delay')
        end

        % Callback function
        function PWVButtonPushed(app, event)
            pwv = app.Solution.TZD(:,3);
            
            % boundaries
            st = ((app.PFrom.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            fn = ((app.PTo.Value - app.Data.obs.ep(1,1))/(app.Data.inf.time.int)) + 1;
            t = app.Data.obs.ep(st:fn,1);
            t = t./3600; % hour
            
            fig = figure('Name','Precipitable Water Vapor','NumberTitle','off','Color',[0.75 0.75 0.75]);
            ax  = axes(fig);
            plot(t,pwv)
            ax.Title.String = 'Precipitable Water Vapor';
            ax.XLabel.String = 'Time (Hour)';
            ax.YLabel.String = 'Precipitable Water Vapor (Millimeter)';
            ax.XGrid = 'on';
            ax.YGrid = 'on';
            legend(ax,'Precipitable Water Vapor')
        end

        % Callback function
        function VCEValueChanged(app, event)
            value = app.VCE.Value;
            if value==1
                app.VR_GP.Editable = 'off'; app.VR_GL.Editable = 'off';
                app.VR_RP.Editable = 'off'; app.VR_RL.Editable = 'off';
                app.VR_EP.Editable = 'off'; app.VR_EL.Editable = 'off';
                app.VR_CP.Editable = 'off'; app.VR_CL.Editable = 'off';
            else
                app.VR_GP.Editable = 'on'; app.VR_GL.Editable = 'on';
                app.VR_RP.Editable = 'on'; app.VR_RL.Editable = 'on';
                app.VR_EP.Editable = 'on'; app.VR_EL.Editable = 'on';
                app.VR_CP.Editable = 'on'; app.VR_CL.Editable = 'on';
                
            end
        end

        % Callback function
        function MeteoSelectionChanged(app, event)
            selectedButton = app.Meteo.SelectedObject;
            if strcmp(selectedButton.Text,'In-situ Measurement')
                app.MeteoFileButton.Enable = 'on';
            else
                app.MeteoFileButton.Enable = 'off';
            end
        end

        % Callback function
        function MeteoFileButtonPushed(app, event)
            [file,path] = uigetfile({'*.**m'},'Select the Meteorological Data');
           if ischar(file)
               meteo = fullfile(path,file);
               app.MeteoFile.Editable = 'on';
               app.MeteoFile.Value    = meteo;
               app.MeteoFile.Editable = 'off';
           else
               return
           end
        end

        % Button pushed function: ImageInputButton
        function ImageInputButtonPushed(app, event)
           [file,path] = uigetfile({'*.csv'},'Select the Meta file');
           if ischar(file)
               meta = fullfile(path,file);
               app.ImageInput.Editable = 'on';
               app.ImageInput.Value    = meta;
               app.ImageInput.Editable = 'off';
           else
               return
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main = uifigure('Visible', 'off');
            app.Main.Name = 'PPPH';
            app.Tabs = uitabgroup(app.Main);
            app.Input = uitab(app.Tabs);
            app.Input.Title = 'DATA IMPORTING';
            app.ClockFileLabel = uilabel(app.Input);
            app.ClockFileLabel.Text = 'Clock File';
            app.ObservationdayLabel = uilabel(app.Input);
            app.ObservationdayLabel.Text = 'Sp3 File';
            app.ElevationCutOffAngleLabel = uilabel(app.Input);
            app.ElevationCutOffAngleLabel.Text = 'Elevation Cut-Off Angle';
            app.FromEditFieldLabel = uilabel(app.Input);
            app.FromEditFieldLabel.Text = 'From';
            app.ToEditFieldLabel = uilabel(app.Input);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.Input, 'numeric');
            app.ToEditField.ValueDisplayFormat = '%.0f';
            app.ToEditField.Value = 86370;
            app.FromEditField = uieditfield(app.Input, 'numeric');
            app.FromEditField.ValueDisplayFormat = '%.0f';
            app.EpochIntervalsecondofdayLabel = uilabel(app.Input);
            app.EpochIntervalsecondofdayLabel.Text = 'Epoch Interval (second of day)';
            app.ProcessingModeDropDownLabel = uilabel(app.Input);
            app.ProcessingModeDropDownLabel.Text = 'Processing Mode';
            app.ProcessingModeDropDown = uidropdown(app.Input);
            app.ProcessingModeDropDown.Items = {'Static', 'Kinematic'};
            app.ProcessingModeDropDown.Value = 'Kinematic';
            app.DegreeLabel = uilabel(app.Input);
            app.DegreeLabel.Text = 'degree';
            app.ElevationAngle = uieditfield(app.Input, 'numeric');
            app.ElevationAngle.Limits = [0 90];
            app.ElevationAngle.ValueDisplayFormat = '%.0f';
            app.ElevationAngle.Value = 10;
            app.AntennaFileButton = uibutton(app.Input, 'push');
            app.AntennaFileButton.ButtonPushedFcn = createCallbackFcn(app, @AntennaFileButtonPushed, true);
            app.AntennaFileButton.Icon = 'icon-folder-128.png';
            app.AntennaFileButton.Text = '';
            app.AntennaFileEditFieldLabel = uilabel(app.Input);
            app.AntennaFileEditFieldLabel.Text = 'Antenna File';
            app.AntennaFile = uieditfield(app.Input, 'text');
            app.AntennaFile.Editable = 'off';
            app.BiasFileButton = uibutton(app.Input, 'push');
            app.BiasFileButton.ButtonPushedFcn = createCallbackFcn(app, @BiasFileButtonPushed, true);
            app.BiasFileButton.Icon = 'icon-folder-128.png';
            app.BiasFileButton.Text = '';
            app.DCBFileLabel = uilabel(app.Input);
            app.DCBFileLabel.Text = 'Bias File';
            app.BiasFile = uieditfield(app.Input, 'text');
            app.BiasFile.Editable = 'off';
            app.SecLabel = uilabel(app.Input);
            app.SecLabel.Text = 'sec';
            app.ClockFileIntervalEditFieldLabel = uilabel(app.Input);
            app.ClockFileIntervalEditFieldLabel.Text = 'Clock File Interval';
            app.ClockFileIntervalEditField = uieditfield(app.Input, 'numeric');
            app.ClockFileIntervalEditField.ValueDisplayFormat = '%.0f';
            app.ClockFileIntervalEditField.Value = 30;
            app.ClockFileButton = uibutton(app.Input, 'push');
            app.ClockFileButton.ButtonPushedFcn = createCallbackFcn(app, @ClockFileButtonPushed, true);
            app.ClockFileButton.Icon = 'icon-folder-128.png';
            app.ClockFileButton.Text = '';
            app.ClockFile = uieditfield(app.Input, 'text');
            app.ClockFile.Editable = 'off';
            app.Sp3FileButton = uibutton(app.Input, 'push');
            app.Sp3FileButton.ButtonPushedFcn = createCallbackFcn(app, @Sp3FileButtonPushed, true);
            app.Sp3FileButton.Icon = 'icon-folder-128.png';
            app.Sp3FileButton.Text = '';
            app.Sp3File = uieditfield(app.Input, 'text');
            app.Sp3File.Editable = 'off';
            app.ObservationFileButton = uibutton(app.Input, 'push');
            app.ObservationFileButton.ButtonPushedFcn = createCallbackFcn(app, @ObservationFileButtonPushed, true);
            app.ObservationFileButton.Icon = 'icon-folder-128.png';
            app.ObservationFileButton.Text = '';
            app.ObservationFileLabel = uilabel(app.Input);
            app.ObservationFileLabel.Text = 'Observation File';
            app.ObservationFile = uieditfield(app.Input, 'text');
            app.ObservationFile.Editable = 'off';
            app.RequiredFilesLabel = uilabel(app.Input);
            app.RequiredFilesLabel.Text = 'Required Files';
            app.Model = uitab(app.Tabs);
            app.Model.Title = 'MODELING OPTIONS';
            app.OtherPanel = uipanel(app.Model);
            app.OtherPanel.Title = 'Other';
            app.SolidTide = uicheckbox(app.OtherPanel);
            app.SolidTide.Text = 'Solid Tide Correction';
            app.SolidTide.Value = true;
            app.RelPath = uicheckbox(app.OtherPanel);
            app.RelPath.Text = 'Relativistic Path Range Correction';
            app.RelPath.Value = true;
            app.RelClock = uicheckbox(app.OtherPanel);
            app.RelClock.Text = 'Relativistic Clock Correction';
            app.RelClock.Value = true;
            app.Atmosphere = uipanel(app.Model);
            app.Atmosphere.Title = 'Atmosphere';
            app.Troposphere = uicheckbox(app.Atmosphere);
            app.Troposphere.ValueChangedFcn = createCallbackFcn(app, @TroposphereValueChanged, true);
            app.Troposphere.Text = 'Trophospheric Correction';
            app.Troposphere.Value = true;
            app.Ionosphere = uicheckbox(app.Atmosphere);
            app.Ionosphere.Enable = 'off';
            app.Ionosphere.Text = 'Ionospheric Correctiıon';
            app.ReceiverPanel = uipanel(app.Model);
            app.ReceiverPanel.Title = 'Receiver';
            app.ARP = uicheckbox(app.ReceiverPanel);
            app.ARP.Text = 'Antenna Reference Point Correction';
            app.ARP.Value = true;
            app.APC = uicheckbox(app.ReceiverPanel);
            app.APC.Text = 'Antenna Phase Center Correction';
            app.APC.Value = true;
            app.SatellitePanel = uipanel(app.Model);
            app.SatellitePanel.Title = 'Satellite';
            app.Windup = uicheckbox(app.SatellitePanel);
            app.Windup.Text = 'Windup Correction';
            app.Windup.Value = true;
            app.MassCenter = uicheckbox(app.SatellitePanel);
            app.MassCenter.Text = 'Mass Center to Antenna Phase Center Correction';
            app.MassCenter.Value = true;
            app.ClockOffset = uicheckbox(app.SatellitePanel);
            app.ClockOffset.Text = 'Clock Offset Correction';
            app.ClockOffset.Value = true;
            app.Filter = uitab(app.Tabs);
            app.Filter.Title = 'FILTERING OPTIONS';
            app.CodeMeasEditFieldLabel = uilabel(app.Filter);
            app.CodeMeasEditFieldLabel.Text = 'Code Meas.';
            app.VR_GP = uieditfield(app.Filter, 'numeric');
            app.VR_GP.Value = 1;
            app.PhaseMeasEditFieldLabel = uilabel(app.Filter);
            app.PhaseMeasEditFieldLabel.Text = 'Phase Meas.';
            app.VR_GL = uieditfield(app.Filter, 'numeric');
            app.VR_GL.Value = 1;
            app.VR_RP = uieditfield(app.Filter, 'numeric');
            app.VR_RP.Value = 2;
            app.VR_EP = uieditfield(app.Filter, 'numeric');
            app.VR_EP.Value = 1;
            app.VR_RL = uieditfield(app.Filter, 'numeric');
            app.VR_RL.Value = 1;
            app.VR_EL = uieditfield(app.Filter, 'numeric');
            app.VR_EL.Value = 1;
            app.CLabel_2 = uilabel(app.Filter);
            app.CLabel_2.Text = 'C';
            app.ELabel = uilabel(app.Filter);
            app.ELabel.Text = 'E';
            app.RLabel = uilabel(app.Filter);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.Filter);
            app.GLabel.Text = 'G';
            app.VR_CP = uieditfield(app.Filter, 'numeric');
            app.VR_CP.Value = 1;
            app.VarianceRatiosforMultiGNSSobservationsLabel = uilabel(app.Filter);
            app.VarianceRatiosforMultiGNSSobservationsLabel.Text = 'Variance Ratios for Multi-GNSS observations';
            app.VR_CL = uieditfield(app.Filter, 'numeric');
            app.VR_CL.Value = 1;
            app.ProcessParametrer = uipanel(app.Filter);
            app.ProcessParametrer.Title = 'Select Process Uncertainty Parameters';
            app.PosParameter_2Label = uilabel(app.ProcessParametrer);
            app.PosParameter_2Label.Text = 'Position';
            app.RecClkParameter_2Label = uilabel(app.ProcessParametrer);
            app.RecClkParameter_2Label.Text = 'Receiver Clock';
            app.TropParameter_2Label = uilabel(app.ProcessParametrer);
            app.TropParameter_2Label.Text = 'Trophosphere';
            app.AmbParameter_2Label = uilabel(app.ProcessParametrer);
            app.AmbParameter_2Label.Text = 'Phase Ambiguities';
            app.PosNoise2_2Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_2Label.Text = 'E';
            app.PosNoise2_3Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_3Label.Text = 'E';
            app.PosNoise2_4Label = uilabel(app.ProcessParametrer);
            app.PosNoise2_4Label.Text = 'E';
            app.AmbNLabel = uilabel(app.ProcessParametrer);
            app.AmbNLabel.Text = 'm2/sec';
            app.AmbNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.AmbNoise2.Editable = 'off';
            app.AmbNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.AmbNoise.ValueDisplayFormat = '%.1f';
            app.AmbNoise.Editable = 'off';
            app.TropNLabel = uilabel(app.ProcessParametrer);
            app.TropNLabel.Text = 'm2/sec';
            app.TropNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.TropNoise2.Value = -7;
            app.TropNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.TropNoise.ValueDisplayFormat = '%.1f';
            app.TropNoise.Value = 1;
            app.RecClkNLabel = uilabel(app.ProcessParametrer);
            app.RecClkNLabel.Text = 'm2/sec';
            app.RecClkNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.RecClkNoise2.Value = 5;
            app.RecClkNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.RecClkNoise.ValueDisplayFormat = '%.13f';
            app.RecClkNoise.Value = 1;
            app.PosNLabel = uilabel(app.ProcessParametrer);
            app.PosNLabel.Text = 'm2/sec';
            app.AmbNoise2_2Label_2 = uilabel(app.ProcessParametrer);
            app.AmbNoise2_2Label_2.Text = 'E';
            app.PosNoise2 = uieditfield(app.ProcessParametrer, 'numeric');
            app.PosNoise2.Value = -1;
            app.PosNoise = uieditfield(app.ProcessParametrer, 'numeric');
            app.PosNoise.ValueDisplayFormat = '%.1f';
            app.PosNoise.Value = 1;
            app.mLabel_2 = uilabel(app.Filter);
            app.mLabel_2.Text = 'm';
            app.CodeMeasLabel = uilabel(app.Filter);
            app.CodeMeasLabel.Text = 'Code Meas.';
            app.PhaseMeasLabel = uilabel(app.Filter);
            app.PhaseMeasLabel.Text = 'Phase Meas.';
            app.PhaseMeas = uieditfield(app.Filter, 'numeric');
            app.PhaseMeas.ValueDisplayFormat = '%5.3f';
            app.PhaseMeas.Value = 0.003;
            app.mLabel = uilabel(app.Filter);
            app.mLabel.Text = 'm';
            app.CodeMeas = uieditfield(app.Filter, 'numeric');
            app.CodeMeas.ValueDisplayFormat = '%5.3f';
            app.CodeMeas.Value = 3;
            app.MeasNoiseLabel = uilabel(app.Filter);
            app.MeasNoiseLabel.Text = 'Standart Deviations for Measurements';
            app.InitialParameter = uipanel(app.Filter);
            app.InitialParameter.Title = 'Select Initial Uncertainty Parameters';
            app.PositionEditFieldLabel = uilabel(app.InitialParameter);
            app.PositionEditFieldLabel.Text = 'Position';
            app.ReceiverClockLabel = uilabel(app.InitialParameter);
            app.ReceiverClockLabel.Text = 'Receiver Clock';
            app.TrophosphereLabel = uilabel(app.InitialParameter);
            app.TrophosphereLabel.Text = 'Trophosphere';
            app.PhaseAmbiguitiesLabel = uilabel(app.InitialParameter);
            app.PhaseAmbiguitiesLabel.Text = 'Phase Ambiguities';
            app.PosNoise2_2Label_2 = uilabel(app.InitialParameter);
            app.PosNoise2_2Label_2.Text = 'E';
            app.PosParameter2_2Label = uilabel(app.InitialParameter);
            app.PosParameter2_2Label.Text = 'E';
            app.PosParameter2_3Label = uilabel(app.InitialParameter);
            app.PosParameter2_3Label.Text = 'E';
            app.AmbLabel = uilabel(app.InitialParameter);
            app.AmbLabel.Text = 'm';
            app.PosParameter2_4Label = uilabel(app.InitialParameter);
            app.PosParameter2_4Label.Text = 'E';
            app.AmbParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.AmbParameter2.ValueDisplayFormat = '%.0f';
            app.AmbParameter2.Value = 1;
            app.AmbParameter = uieditfield(app.InitialParameter, 'numeric');
            app.AmbParameter.ValueDisplayFormat = '%.1f';
            app.AmbParameter.Value = 2;
            app.TropLabel = uilabel(app.InitialParameter);
            app.TropLabel.Text = 'm';
            app.TropParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.TropParameter2.ValueDisplayFormat = '%.0f';
            app.TropParameter2.Value = -1;
            app.TropParameter = uieditfield(app.InitialParameter, 'numeric');
            app.TropParameter.ValueDisplayFormat = '%.1f';
            app.TropParameter.Value = 5;
            app.RecClkLabel2 = uilabel(app.InitialParameter);
            app.RecClkLabel2.Text = 'm';
            app.RecClkParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.RecClkParameter2.ValueDisplayFormat = '%.0f';
            app.RecClkParameter2.Value = 5;
            app.RecClkParameter = uieditfield(app.InitialParameter, 'numeric');
            app.RecClkParameter.ValueDisplayFormat = '%.1f';
            app.RecClkParameter.Value = 1;
            app.PosLabel = uilabel(app.InitialParameter);
            app.PosLabel.Text = 'm';
            app.PosParameter2 = uieditfield(app.InitialParameter, 'numeric');
            app.PosParameter2.ValueDisplayFormat = '%.0f';
            app.PosParameter = uieditfield(app.InitialParameter, 'numeric');
            app.PosParameter.ValueDisplayFormat = '%.1f';
            app.Output = uitab(app.Tabs);
            app.Output.Title = 'OUTPUT';
            app.ObservationFileLabel_2 = uilabel(app.Output);
            app.ObservationFileLabel_2.Text = 'Meta file';
            app.NLabel = uilabel(app.Output);
            app.NLabel.Text = 'N';
            app.YLabel = uilabel(app.Output);
            app.YLabel.Text = 'E';
            app.ZLabel = uilabel(app.Output);
            app.ZLabel.Text = 'D';
            app.LADownLabel = uilabel(app.Output);
            app.LADownLabel.Text = 'm';
            app.LADown = uieditfield(app.Output, 'numeric');
            app.LADown.ValueDisplayFormat = '%10.4f';
            app.LAEastLabel = uilabel(app.Output);
            app.LAEastLabel.Text = 'm';
            app.LAEast = uieditfield(app.Output, 'numeric');
            app.LAEast.ValueDisplayFormat = '%10.4f';
            app.LANorthLabel = uilabel(app.Output);
            app.LANorthLabel.Text = 'm';
            app.LANorth = uieditfield(app.Output, 'numeric');
            app.LANorth.ValueDisplayFormat = '%10.4f';
            app.LALabel = uilabel(app.Output);
            app.LALabel.Text = 'Please insert the lever arm values between the camera and GNSS receiver in the body coordinate frame (the North (N), East (E), and Down (D) directions).';
            app.ImageInputButton = uibutton(app.Output, 'push');
            app.ImageInputButton.ButtonPushedFcn = createCallbackFcn(app, @ImageInputButtonPushed, true);
            app.ImageInputButton.Icon = 'icon-folder-128.png';
            app.ImageInputButton.Text = '';
            app.ImageInput = uieditfield(app.Output, 'text');
            app.ImageInput.Editable = 'off';
            app.ImageInputLabel = uilabel(app.Output);
            app.ImageInputLabel.Text = 'Please insert the metafile for images obtained by UAV here. This file should include image names, GPS time stamps, and rotation angles (roll, pitch, yaw).';
            app.RunButton = uibutton(app.Main, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'play_button-512.png';
            app.RunButton.Text = 'RUN';
            app.LoadSavedConfigurationButton = uibutton(app.Main, 'push');
            app.LoadSavedConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSavedConfigurationButtonPushed, true);
            app.LoadSavedConfigurationButton.Text = 'Load Saved Configuration';
            app.DefaultConfigurationButton = uibutton(app.Main, 'push');
            app.DefaultConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultConfigurationButtonPushed, true);
            app.DefaultConfigurationButton.Text = 'Default Configuration';
            app.SaveConfigurationButton = uibutton(app.Main, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.MainLabel = uilabel(app.Main);
            app.MainLabel.Text = 'PPPH-UAV';
            % Show the figure after all components are created
            app.Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PPPH_UAV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main)
        end
    end
end